classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout3                    matlab.ui.container.GridLayout
        MADEBYZhaoYanbo2024XUPTLabel   matlab.ui.control.Label
        IEEE80211aOFDMSImulationLabel  matlab.ui.control.Label
        ControlsPanel                  matlab.ui.container.Panel
        SignalStrengthLamp             matlab.ui.control.Lamp
        SignalStrengthLampLabel        matlab.ui.control.Label
        EditField_Seed                 matlab.ui.control.EditField
        ScremblingSeedLabel            matlab.ui.control.Label
        EditField_Message              matlab.ui.control.EditField
        MessageLabel                   matlab.ui.control.Label
        Button_Run                     matlab.ui.control.Button
        SNRSlider                      matlab.ui.control.Slider
        SNRSliderLabel                 matlab.ui.control.Label
        CodeRateButtonGroup            matlab.ui.container.ButtonGroup
        MbpsButton36                   matlab.ui.control.RadioButton
        MbpsButton6                    matlab.ui.control.RadioButton
        ResultsConstellationDiagramPanel  matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_Run
        function Button_RunPushed(app, event)
            tic
            addpath(genpath('./lib'))
            UTILS.refresh_gui(app.MbpsButton6,app.MbpsButton36,app.EditField_Message,app.EditField_Seed,app.SNRSlider,app.UIAxes,app.UIAxes2,app.UIAxes3,app.UIAxes4, app.SignalStrengthLamp)
            toc
        end

        % Callback function
        function CodeRateButtonGroupButtonDown(app, event)

        end

        % Value changed function: SNRSlider
        function SNRSliderValueChanged(app, event)
            tic
            addpath(genpath('./lib'))
            UTILS.refresh_gui(app.MbpsButton6,app.MbpsButton36,app.EditField_Message,app.EditField_Seed,app.SNRSlider,app.UIAxes,app.UIAxes2,app.UIAxes3,app.UIAxes4, app.SignalStrengthLamp)
            toc
        end

        % Selection changed function: CodeRateButtonGroup
        function CodeRateButtonGroupSelectionChanged(app, event)
            tic
            addpath(genpath('./lib'))
            UTILS.refresh_gui(app.MbpsButton6,app.MbpsButton36,app.EditField_Message,app.EditField_Seed,app.SNRSlider,app.UIAxes,app.UIAxes2,app.UIAxes3,app.UIAxes4, app.SignalStrengthLamp)
            toc
        end

        % Value changing function: SNRSlider
        function SNRSliderValueChanging(app, event)
            tic
            addpath(genpath('./lib'))
            UTILS.refresh_gui(app.MbpsButton6,app.MbpsButton36,app.EditField_Message,app.EditField_Seed,event,app.UIAxes,app.UIAxes2,app.UIAxes3,app.UIAxes4, app.SignalStrengthLamp)
            toc
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout3 = uigridlayout(app.UIFigure);
            app.ResultsConstellationDiagramPanel = uipanel(app.GridLayout3);
            app.ResultsConstellationDiagramPanel.Title = 'Results (Constellation Diagram)';
            app.GridLayout = uigridlayout(app.ResultsConstellationDiagramPanel);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.ControlsPanel = uipanel(app.GridLayout3);
            app.ControlsPanel.Title = 'Controls';
            app.CodeRateButtonGroup = uibuttongroup(app.ControlsPanel);
            app.CodeRateButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CodeRateButtonGroupSelectionChanged, true);
            app.CodeRateButtonGroup.Title = 'Code Rate';
            app.MbpsButton6 = uiradiobutton(app.CodeRateButtonGroup);
            app.MbpsButton6.Text = '6 Mbps';
            app.MbpsButton6.Value = true;
            app.MbpsButton36 = uiradiobutton(app.CodeRateButtonGroup);
            app.MbpsButton36.Text = '36 Mbps';
            app.SNRSliderLabel = uilabel(app.ControlsPanel);
            app.SNRSliderLabel.Text = 'SNR';
            app.SNRSlider = uislider(app.ControlsPanel);
            app.SNRSlider.ValueChangedFcn = createCallbackFcn(app, @SNRSliderValueChanged, true);
            app.SNRSlider.ValueChangingFcn = createCallbackFcn(app, @SNRSliderValueChanging, true);
            app.SNRSlider.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.SNRSlider.Value = 40;
            app.Button_Run = uibutton(app.ControlsPanel, 'push');
            app.Button_Run.ButtonPushedFcn = createCallbackFcn(app, @Button_RunPushed, true);
            app.Button_Run.Text = 'Run';
            app.MessageLabel = uilabel(app.ControlsPanel);
            app.MessageLabel.Text = 'Message';
            app.EditField_Message = uieditfield(app.ControlsPanel, 'text');
            app.EditField_Message.Placeholder = 'Across the Great Wall we can reach every corner in the world.';
            app.EditField_Message.Value = 'Across the Great Wall we can reach every corner in the world.';
            app.ScremblingSeedLabel = uilabel(app.ControlsPanel);
            app.ScremblingSeedLabel.Text = 'Seed';
            app.EditField_Seed = uieditfield(app.ControlsPanel, 'text');
            app.EditField_Seed.Placeholder = '[1 0 1 0 1 0 1]';
            app.EditField_Seed.Value = '[1 0 1 0 1 0 1]';
            app.SignalStrengthLampLabel = uilabel(app.ControlsPanel);
            app.SignalStrengthLampLabel.Text = 'Signal Strength';
            app.SignalStrengthLamp = uilamp(app.ControlsPanel);
            app.IEEE80211aOFDMSImulationLabel = uilabel(app.GridLayout3);
            app.IEEE80211aOFDMSImulationLabel.Text = 'IEEE 802.11a OFDM SImulation';
            app.MADEBYZhaoYanbo2024XUPTLabel = uilabel(app.GridLayout3);
            app.MADEBYZhaoYanbo2024XUPTLabel.Text = 'MADE BY Zhao Yanbo, 2024 @ XUPT';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end